classdef MiniDAQapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ModelNameLabel   matlab.ui.control.Label
        StartStopButton  matlab.ui.control.StateButton
        IMUaccAxes       matlab.ui.control.UIAxes
        IMUanglesAxes    matlab.ui.control.UIAxes
        ADCAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % variables from workspace
        buildDir        % simulink build directory
        mdlName = 'MiniDAQ';         % name of the built model
        tg              % the RT target
        tgName          % the RT target name
        hInst           % handle to the instrumentation object
        % static parameters
        startIcon = fullfile('icons','Run_24.png')
        stopIcon = fullfile('icons','stop_24.png')
    end
    
    methods (Access = private)
        
        function setupInstrumentation(app)
            % create an instrumentation object
            app.hInst = slrealtime.Instrument(app.mdlName);
            
            % scope signals
            app.hInst.connectLine(app.ADCAxes,[app.mdlName,'/ADC/ADC0'],1);
            app.hInst.connectLine(app.ADCAxes,[app.mdlName,'/ADC/ADC1'],1);
            app.hInst.connectLine(app.ADCAxes,[app.mdlName,'/ADC/ADC2'],1);
            app.hInst.connectLine(app.ADCAxes,[app.mdlName,'/ADC/ADC3'],1);
            
            app.hInst.connectLine(app.IMUanglesAxes,[app.mdlName,'/IMU/roll'],1);
            app.hInst.connectLine(app.IMUanglesAxes,[app.mdlName,'/IMU/pitch'],1);
            app.hInst.connectLine(app.IMUanglesAxes,[app.mdlName,'/IMU/yaw'],1);
            app.hInst.connectLine(app.IMUaccAxes,[app.mdlName,'/IMU/ax'],1);
            app.hInst.connectLine(app.IMUaccAxes,[app.mdlName,'/IMU/ay'],1);
            app.hInst.connectLine(app.IMUaccAxes,[app.mdlName,'/IMU/az'],1);
            
            
            % connect callback to update axes
%             app.hInst.connectCallback(@app.regularCallback);
            
            app.tg.removeAllInstruments;
            % add the instrument object to the app
            app.tg.addInstrument(app.hInst);
            
            legend(app.ADCAxes,'ADC1','ADC2','ADC3','ADC4', 'location', 'northwest');
            legend(app.IMUanglesAxes,'roll','pitch','yaw', 'location', 'northwest');
            legend(app.IMUaccAxes,'ax','ay','az', 'location', 'northwest');
            
            app.ADCAxes.YLim = [-10 10];
            app.IMUaccAxes.YLim = [-3 3];
            app.IMUanglesAxes.YLim = [-90 90];

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Running the Mini-DAQ startup function ...');
            
            assignin('base','myHandle', app)
            %%% get model information
            d.Value = 0.1;
            d.Message = 'Loading workspace data';
            
            app.mdlName = evalin('base','mdlName');
            app.buildDir = evalin('base','buildDir');
            app.tgName = evalin('base','tgName');

            modelVersion = evalin('base','mdlVersion');
            app.ModelNameLabel.Text = ['Model: ', app.mdlName, ' Version', modelVersion];
            
            %%% connect the RT and instrument objects
            d.Value = 0.2;
            d.Message = ['Connecting to the RT target',app.tgName, ' ...'];
            
            app.tg = slrealtime(app.tgName);
            
            % load the model on to the target
            d.Value = 0.5;
            d.Message = ['Loading the model ',app.mdlName,' ...'];
            
            app.tg.stop;
            app.tg.removeAllInstruments();
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            
            app.StartStopButton.Icon = app.startIcon;
            app.StartStopButton.Text = 'Start';
            
            app.setupInstrumentation;
            
            % close progress bar
            d.close();
        end

        % Value changed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if strcmp(app.StartStopButton.Icon, app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StartStopButton.Icon = app.stopIcon;
                app.StartStopButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StartStopButton.Icon = app.startIcon;
                app.StartStopButton.Text = 'Start';
                run('postProcess.m');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ADCAxes = uiaxes(app.UIFigure);
            title(app.ADCAxes, 'ADC')
            xlabel(app.ADCAxes, 'time(s)')
            ylabel(app.ADCAxes, 'input (V)')
            zlabel(app.ADCAxes, 'Z')
            app.IMUanglesAxes = uiaxes(app.UIFigure);
            title(app.IMUanglesAxes, 'IMU rotations')
            xlabel(app.IMUanglesAxes, 'X')
            ylabel(app.IMUanglesAxes, 'angle (deg)')
            zlabel(app.IMUanglesAxes, 'Z')
            app.IMUaccAxes = uiaxes(app.UIFigure);
            title(app.IMUaccAxes, 'IMU accelerations')
            xlabel(app.IMUaccAxes, 'X')
            ylabel(app.IMUaccAxes, 'acc (m/s^2)')
            zlabel(app.IMUaccAxes, 'Z')
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start';
            app.ModelNameLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MiniDAQapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end